classdef Voltage_Relay_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Lamp4              matlab.ui.control.Lamp
        Lamp3              matlab.ui.control.Lamp
        Lamp2              matlab.ui.control.Lamp
        Lamp               matlab.ui.control.Lamp
        Relay4Switch       matlab.ui.control.Switch
        Relay4SwitchLabel  matlab.ui.control.Label
        Relay3Switch       matlab.ui.control.Switch
        Relay3SwitchLabel  matlab.ui.control.Label
        Relay2Switch       matlab.ui.control.Switch
        Relay2SwitchLabel  matlab.ui.control.Label
        Relay1Switch       matlab.ui.control.Switch
        Relay1SwitchLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = arduino('COM5');
            configurePin(app.a, 'D5','DigitalOutput');
            configurePin(app.a, 'D4','DigitalOutput');
            configurePin(app.a, 'D3','DigitalOutput');
            configurePin(app.a, 'D2','DigitalOutput');
            
        end

        % Value changed function: Relay4Switch
        function Relay4(app, event)
            value = app.Relay4Switch.Value;
            if strcmp(value, 'On')
                app.Lamp4.Color = 'green';
                writeDigitalPin(app.a, 'D5', 0);
            else
                app.Lamp4.Color ='red';
                writeDigitalPin(app.a, 'D5', 1);

            end
            
        end

        % Value changed function: Relay3Switch
        function Relay3(app, event)
            value = app.Relay3Switch.Value;
            if strcmp(value, 'On')
                app.Lamp3.Color = 'green';
                writeDigitalPin(app.a, 'D4', 0);
            else 
                app.Lamp3.Color = 'red';
                writeDigitalPin(app.a, 'D4', 1);
                
            end


        end

        % Value changed function: Relay2Switch
        function Relay2(app, event)
            value = app.Relay2Switch.Value;
             if strcmp(value, 'On')
                app.Lamp2.Color = 'green';
                writeDigitalPin(app.a, 'D3', 0);
            else 
                app.Lamp2.Color = 'red';
                writeDigitalPin(app.a, 'D3', 1);
                
            end
            
        end

        % Value changed function: Relay1Switch
        function Relay1(app, event)
            value = app.Relay1Switch.Value;
             if strcmp(value, 'On')
                app.Lamp.Color = 'green';
                writeDigitalPin(app.a, 'D2', 0);
            else 
                app.Lamp.Color = 'red';
                writeDigitalPin(app.a, 'D2', 1);
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Relay1SwitchLabel = uilabel(app.UIFigure);
            app.Relay1SwitchLabel.Text = 'Relay 1';
            app.Relay1Switch = uiswitch(app.UIFigure, 'slider');
            app.Relay1Switch.ValueChangedFcn = createCallbackFcn(app, @Relay1, true);
            app.Relay1Switch.Value = 'On';
            app.Relay2SwitchLabel = uilabel(app.UIFigure);
            app.Relay2SwitchLabel.Text = 'Relay 2';
            app.Relay2Switch = uiswitch(app.UIFigure, 'slider');
            app.Relay2Switch.ValueChangedFcn = createCallbackFcn(app, @Relay2, true);
            app.Relay2Switch.Value = 'On';
            app.Relay3SwitchLabel = uilabel(app.UIFigure);
            app.Relay3SwitchLabel.Text = 'Relay 3';
            app.Relay3Switch = uiswitch(app.UIFigure, 'slider');
            app.Relay3Switch.ValueChangedFcn = createCallbackFcn(app, @Relay3, true);
            app.Relay3Switch.Value = 'On';
            app.Relay4SwitchLabel = uilabel(app.UIFigure);
            app.Relay4SwitchLabel.Text = 'Relay 4';
            app.Relay4Switch = uiswitch(app.UIFigure, 'slider');
            app.Relay4Switch.ValueChangedFcn = createCallbackFcn(app, @Relay4, true);
            app.Relay4Switch.Value = 'On';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp4 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voltage_Relay_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end